classdef page3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        EditField_2  matlab.ui.control.NumericEditField
        Label_6      matlab.ui.control.Label
        EditField_5  matlab.ui.control.NumericEditField
        Label_5      matlab.ui.control.Label
        EditField_4  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
        EditField_3  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField_1  matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)

            delete(app.UIFigure)
            page2
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            h=waitbar(0,'请稍等，计算耦合体系本征频率中');
            L=app.EditField_1.Value;
            a=app.EditField_2.Value;
            cs=app.EditField_3.Value;
            rho=app.EditField_4.Value;
            a0=app.EditField_5.Value;
            ca = 345;% 空气中声速
            sigma = 0.120;% 气球面密度
            cs_ca=cs/ca;L_a=L/a;sigma_a_rho=sigma/a/rho;
            load('rootBessel.mat')
            load('rootBesselDiff.mat')
            for n=0:10
                waitbar(n/10,h)
                OmegaResonance(n,10, cs_ca,L_a, sigma_a_rho,rootBessel,rootBesselDiff);
            end
            delete(h);
            save('para.mat','L_a','sigma_a_rho','cs_ca','a0','L','a','ca')

            fileList=dir('./OmegaRe/*.mat');
            order_max=10;% 需要修改%%%%%%%%%%%%%%%
            Omega=zeros(length(fileList),order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                while length(OmegaRe)<order_max
                    OmegaRe=[OmegaRe nan];
                end
                Omega(ni,:)=OmegaRe(1:order_max);
            end
            save('OmegaRe.mat',"Omega")

            fileList=dir('./Trans/*.mat');

            transMat=zeros(length(fileList),order_max,order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                transMat(ni,:,:)=trans(1:order_max,1:order_max);
            end
            save('transMat.mat',"transMat")

            delete(app.UIFigure)
            page4
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '参数设置';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '上一步';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '下一步';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '鼓的高度';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.Value = 0.1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '膜上波速';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 20;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '空气密度';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Value = 1.184;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '小球下落位置';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 0.1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '鼓的半径';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 0.047;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'para_fig.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end