classdef page4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_7  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Slider_2  matlab.ui.control.Slider
        Label_2   matlab.ui.control.Label
        Slider    matlab.ui.control.Slider
        Label     matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            delete(app.UIFigure)
            page3
        end

        % Callback function
        function Button_3Pushed(app, event)


        end

        % Callback function
        function Button_2Pushed(app, event)
            delete(app.UIFigure)
            page5
        end

        % Callback function
        function Button_4Pushed(app, event)

        end

        % Button pushed function: Button_2
        function Button_5Pushed(app, event)
            load('.\para.mat');
            load('.\wcn.mat');
            load('.\Omega.mat');
            load('.\cut_num.mat');
            load('.\shape.mat');
            fn=10000; % 横轴划分的个数
            omega_limit=10;ca=340;a=0.5;
            x=linspace(0,omega_limit*ca/a/2/pi,fn);
            y=zeros(1,fn);
            for nn=0:order_max
                for mm=1:order_max
                    y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
                end
            end
            y=y/max(y);
            plot(app.UIAxes,x,y)
            xlim(app.UIAxes,[0 2e3])
            title(app.UIAxes,'频谱图')
            save('A_f_data.mat','x','y')
            %%
            z0=0.5;
            k=1;
            t=0;
                            load('s_w.mat');
                load('s_h.mat');
            while 1
                t=t+0.0005;
                shape_mmm=zeros(r_num,theta_num);
                shape_ccc=zeros(r_num,theta_num);
                for nn=0:order_max
                    for mm=1:order_max
                        shape_mmm=shape_mmm+shape_m{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t);
                        shape_ccc=shape_ccc+shape_c{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*sin((k-1/2)*pi*z0);
                    end
                end

                s1=surfShape(app.UIAxes2,shape_mmm,r,theta);
                s1.EdgeColor="none";
                app.UIAxes2.NextPlot='add';
                s2=surfShape(app.UIAxes2,shape_ccc-5,r,theta);
                s2.EdgeColor="none";
                app.UIAxes2.NextPlot='replacechildren';
                view(app.UIAxes2,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2,[-1,1,-1,1,-8,3]); % 显示不全调节最后两个参数
                drawnow
                pause(0.1)
            end

        end

        % Callback function
        function Button_6Pushed(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            see_where=app.Slider.Value;
            save('s_w_page4.mat','see_where');
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            see_high=app.Slider_2.Value;
            save('s_h_page4.mat','see_high');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            load('.\para.mat');
            load('.\wcn.mat');
            load('.\Omega.mat');
            load('.\cut_num.mat');
            fn=10000; % 横轴划分的个数
            omega_limit=10;ca=340;a=0.5;
            x=linspace(0,omega_limit*ca/a/2/pi,fn);
            y=zeros(1,fn);
            for nn=0:order_max
                for mm=1:order_max
                    y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
                end
            end
            y=y/max(y);
            fs=10000;
            NN=3000;

            % 我看不懂在写啥
            %             yy=y(1).*sin(2*pi.*x(1).*(0:NN-1)./fs);
            %             for i=2:length(y)
            %                 yy=y+AA(i).*sin(2*pi.*x(i).*(0:NN-1)./fs);
            %             end
            %             sound(yy,fs);
        end

        % Button pushed function: Button_4
        function Button_4Pushed2(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
            page_5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_6
        function Button_6Pushed2(app, event)
            page5_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_5
        function Button_5Pushed2(app, event)
            page6
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '频率(Hz)')
            ylabel(app.UIAxes, '响度(A.U.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 500 1000 1500 2000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '方位角';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '仰角';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.MajorTicks = [-90 -45 0 45 90];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_2.Text = ' 给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Text = '鼓上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed2, true);
            app.Button_4.Text = '参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed2, true);
            app.Button_5.Text = '数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed2, true);
            app.Button_6.Text = '鼓上某点的振动';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '播放声音';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end