classdef page4_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label_9          matlab.ui.control.Label
        Label_8          matlab.ui.control.Label
        Label_7          matlab.ui.control.Label
        Label_6          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button_log       matlab.ui.control.RadioButton
        Button_line      matlab.ui.control.RadioButton
        Button_9         matlab.ui.control.Button
        EditField_theta  matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        EditField_r      matlab.ui.control.NumericEditField
        Label            matlab.ui.control.Label
        Slider_2         matlab.ui.control.Slider
        Slider_2Label    matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        Button_8         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2_3        matlab.ui.control.UIAxes
        UIAxes2_2        matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_r, EditField_theta
        function EditField_thetaValueChanged(app, event)
            app.UIAxes.NextPlot='replacechildren';

            n= app.EditField_theta.Value;
            m=app.EditField_r.Value;
            load('.\para.mat')
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\OmegaRe.mat');
            load('.\shape.mat')
            %a=1; %归一化后
            r_num=100;
            theta_num=100;% 切分的数目
            r=linspace(0,1,r_num)';
            theta=linspace(0,2*pi-2*pi/theta_num,theta_num);

            ome_mem=rootBessel(n+1,:)*cs_ca;
            ome_cap=[];
            for ii=1:5
                ome_cap=[ome_cap sqrt(rootBesselDiff(n+1,:).^2+(ii*pi./L_a).^2)];
            end
            save('omega_m_c_a.mat','Omega','ome_mem','ome_cap');

            if app.Button_line.Value==true
                li_mem=plot(app.UIAxes,ome_mem(1,1:order_max),3*ones(1,order_max),'.','Color','r','MarkerSize',20);
                app.UIAxes.NextPlot='add';
                li_cap=plot(app.UIAxes,ome_cap,2*ones(size(ome_cap)),'.','Color','g','MarkerSize',20);
                li_all=plot(app.UIAxes,Omega(1,1:order_max),ones(1,order_max),'.','Color','y','MarkerSize',20);
                app.UIAxes.XScale='linear';
            else
                lo_mem=semilogx(app.UIAxes,ome_mem(1,1:order_max),3*ones(1,order_max),'.','Color','r','MarkerSize',20);
                app.UIAxes.NextPlot='add';
                lo_cap=semilogx(app.UIAxes,ome_cap,2*ones(size(ome_cap)),'.','Color','g','MarkerSize',20);
                lo_all=semilogx(app.UIAxes,Omega(1,1:order_max),ones(1,order_max),'.','Color','y','MarkerSize',20);
                app.UIAxes.XScale='log';
            end

            a=1;ca=340;
            deep=5;
            
            omega_cou=Omega*ca/a;
            omega_mem=rootBessel*cs/a;
            omega_cav=sqrt(rootBesselDiff.^2+(pi./L_a).^2);
            
            omega_cou_tmp=omega_cou(n+1,m);
            omega_mem_tmp=omega_mem(n+1,m);
            if n==0
                omega_cav_tmp=omega_cav(n+1,m);
            else
                omega_cav_tmp=omega_cav(n+1,m+1);
            end
            load('s_w_page4_0.mat');
            load('s_h_page4_0.mat');
            cnt=0;
            for t=0:0.0005:0.1
                cnt=cnt+1;
                s=surfShape(app.UIAxes2_2,shape_m{n+1,m}*cos(omega_mem_tmp*t),r,theta);
                s.EdgeColor="none";
                axis(app.UIAxes2_2,[-1,1,-1,1,-2.5,2.5]);

                view(app.UIAxes2_2,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2_2,'off')

                s0=surfShape(app.UIAxes2_3,shape_c{n+1,m}*cos(omega_cav_tmp*t),r,theta);
                s0.EdgeColor="none";
                axis(app.UIAxes2_3,[-1,1,-1,1,-2.5,2.5]);
                view(app.UIAxes2_3,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2_3,'off')
                app.UIAxes2_3.NextPlot='replacechildren';

                s1=surfShape(app.UIAxes2,shape_c{n+1,m}*cos(omega_cou_tmp*t)-deep,r,theta);
                s1.EdgeColor="none";
                app.UIAxes2.NextPlot='add';
                s2=surfShape(app.UIAxes2,shape_m{n+1,m}*cos(omega_cou_tmp*t)+L,r,theta);
                s2.EdgeColor="none";
                axis(app.UIAxes2,[-1,1,-1,1,-10,5]);
                view(app.UIAxes2,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2,'off')
                app.UIAxes2.NextPlot='replacechildren';

                drawnow

                pause(0.1)
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            see_where=app.Slider.Value;
            save('s_w_page4_0.mat','see_where');
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            see_high=app.Slider_2.Value;
            save('s_h_page4_0.mat','see_high');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            load('omega_m_c_a.mat');
            if app.Button_line.Value==true
                li_mem=plot(app.UIAxes,ome_mem(1,1:order_max),3*ones(1,10),'.','Color','r','MarkerSize',20);
                app.UIAxes.NextPlot='add';
                li_cap=plot(app.UIAxes,ome_cap,2*ones(size(ome_cap)),'.','Color','g','MarkerSize',20);
                li_all=plot(app.UIAxes,Omega(1,1:order_max),ones(1,order_max),'.','Color','y','MarkerSize',20);
                app.UIAxes.XScale='linear';
            else
                lo_mem=semilogx(app.UIAxes,ome_mem(1,1:order_max),3*ones(1,order_max),'.','Color','r','MarkerSize',20);
                app.UIAxes.NextPlot='add';
                lo_cap=semilogx(app.UIAxes,ome_cap,2*ones(size(ome_cap)),'.','Color','g','MarkerSize',20);
                lo_all=semilogx(app.UIAxes,Omega(1,1:order_max),ones(1,order_max),'.','Color','y','MarkerSize',20);
                app.UIAxes.XScale='log';
            end

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            page4
            delete(app.UIFigure)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page_5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            page5_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            page6
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            colormap(app.UIAxes2_2, 'autumn')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            colormap(app.UIAxes2_3, 'winter')
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [0 4];
            app.UIAxes.XTick = [0 5 10 15 20];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = ' 不同模式下的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '给定初始条件的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '鼓上某点的振动';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '参数设置';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '数据导出';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '方位角';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '仰角';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.MajorTicks = [-90 -45 0 45 90];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'r方向模式数';
            app.EditField_r = uieditfield(app.UIFigure, 'numeric');
            app.EditField_r.ValueChangedFcn = createCallbackFcn(app, @EditField_thetaValueChanged, true);
            app.EditField_r.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'θ方向模式数';
            app.EditField_theta = uieditfield(app.UIFigure, 'numeric');
            app.EditField_theta.ValueChangedFcn = createCallbackFcn(app, @EditField_thetaValueChanged, true);
            app.EditField_theta.Value = 1;
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '鼓上某点的振动';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = ' 横轴';
            app.Button_line = uiradiobutton(app.ButtonGroup);
            app.Button_line.Text = '线性坐标';
            app.Button_line.Value = true;
            app.Button_log = uiradiobutton(app.ButtonGroup);
            app.Button_log.Text = '对数坐标';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '膜';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '腔';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '耦合体系';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '特征频率';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '膜的自由振动';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '腔的自由振动';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '耦合体系的自由振动';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end