classdef page5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Slider_5                matlab.ui.control.Slider
        Slider_5Label           matlab.ui.control.Label
        Slider_4                matlab.ui.control.Slider
        Slider_4Label           matlab.ui.control.Label
        EditField_h_change      matlab.ui.control.NumericEditField
        Label_11                matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        Slider_3                matlab.ui.control.Slider
        EditField_theta_change  matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        EditField_r_change      matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Slider_2                matlab.ui.control.Slider
        Label                   matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        UIAxes2_3               matlab.ui.control.UIAxes
        UIAxes2_2               matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_r_change
        function EditField_r_changeValueChanged(app, event)
            load('para.mat');
            load("OmegaRe.mat"); % 计算得到的OmegaRe
            load('transMat.mat'); % a^k_ni
            load('rootBessel.mat');
            load('rootBesselDiff.mat');
            load('shape.mat')
            load('wcn.mat')
            load('cut_num.mat')
            r0=app.EditField_r_change.Value*a;
            h00=app.EditField_h_change.Value*L;
            app.Slider.Value=double(app.EditField_r_change.Value);
            theta0=app.EditField_theta_change.Value;
            x0=r0*cos(theta0/180*pi);
            y0=r0*sin(theta0/180*pi);
            hh=0:0.001:L;
            th=0:pi/200:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                yy(hi,:)=hh(hi);
                zz(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            drum_draw.FaceAlpha=0.4;
            view(app.UIAxes2,180,-120);
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*L,1.1*L,-1.1*a,1.1*a]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx));
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.5;
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,h00,y0,'.','color','b','MarkerSize',15);

            %view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            view(app.UIAxes2,[0,-0.7,-1]);
            app.UIAxes2.NextPlot='replacechildren';

            z_1=[];
            k=1;
            r0=app.EditField_r_change.Value;
            theta0=app.EditField_theta_change.Value;
            % 画p-t
            for t=0:0.0005:0.1
                shape_1=zeros(r_num,theta_num);
                for nn=0:order_max
                    for mm=1:order_max
                        shape_1=shape_1+shape_c{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*sin((k-1/2)*pi*h00);
                    end
                end
                z_tmp=shape_1(1+floor(r0*r_num),1+floor(theta0/180*floor(theta_num/2)));
                z_1=[z_1 z_tmp];
            end
            % 画面
            plot(app.UIAxes,(0:(length(z_1)-1))/length(z_1)*0.1,z_1,'Color','g')
            ylim(app.UIAxes,[min(z_1)-eps max(z_1)+eps])
            title(app.UIAxes,'耦合体系腔的振动','Color','w')
            app.UIAxes.NextPlot='replacechildren';
            z_num=100;
            z=linspace(0,L,z_num);
            zeta=cell(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0
                        epsl=L/a*sqrt(Omega(nn+1,mm).^2-rootBesselDiff(nn+1,mm).^2);
                    else
                        epsl=L/a*sqrt(Omega(nn+1,mm).^2-rootBesselDiff(nn+1,mm+1).^2);
                    end

                    if Omega(nn+1,mm)<rootBesselDiff(nn+1,mm+(nn~=1))
                        zeta{nn+1,mm}=cosh(epsl-epsl*z)/cosh(epsl);
                    elseif Omega(nn+1,mm)==rootBesselDiff(nn+1,mm+(nn~=1))
                        zeta{nn+1,mm}=ones(size(z));
                    else
                        zeta{nn+1,mm}=cos(epsl*z)+tan(epsl)*sin(epsl*z);
                    end

                end
            end

            z_id=ceil(h00/L*z_num+eps);

            for t=0:0.0002:0.1
                shape_1=zeros(r_num,theta_num);
                for nn=0:order_max
                    for mm=1:order_max
                        zeta_tmp=zeta{nn+1,mm};
                        shape_1=shape_1+shape_c{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*zeta_tmp(z_id);
                    end
                end

                shape_2=zeros(r_num,z_num);
                for nn=0:order_max
                    for mm=1:order_max
                        shape_tmp=shape_c{nn+1,mm};
                        shape_2=shape_2+shape_tmp(:,ceil(theta0/180/(pi/theta_num)+eps))*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*zeta{nn+1,mm};
                    end
                end

                load('s_w_page5_1.mat')
                load('s_h_page5_1.mat')
                %     surfShapee(shape,r,theta);
                %     pause(0.01)
                f1=surfShape(app.UIAxes2_2,shape_1,r,theta);
                f1.EdgeColor="none";

                view(app.UIAxes2_2,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2_2,'off')
                drawnow
                app.UIAxes2_2.NextPlot='replacechildren';

                f2=surf(app.UIAxes2_3,r,z,shape_2);
                f2.EdgeColor="none";
                view(app.UIAxes2_3,see_where,see_high);  % 设置视点位置
                axis(app.UIAxes2_3,'off')
                drawnow
                app.UIAxes2_3.NextPlot='replacechildren';
            end
            save('p_t.mat','z_1');
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            see_where00=app.Slider_4.Value/180;
            save('s_w_page5_1.mat','see_where');
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            see_high00=app.Slider_5.Value/180;
            save('s_h.mat_page5_1','see_high');
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            r0=floor(app.Slider.Value*100)/100;
            app.EditField_r_change.Value=r0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            xita0=floor(app.Slider_2.Value*100)/100;
            app.EditField_theta_change.Value=xita0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            h0=floor(app.Slider_3.Value*100)/100;
            app.EditField_h_change.Value=h0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: EditField_theta_change
        function EditField_theta_changeValueChanged(app, event)
            xita0=app.EditField_theta_change.Value;
            app.Slider_2.Value=xita0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: EditField_h_change
        function EditField_h_changeValueChanged(app, event)
            h00=app.EditField_h_change.Value;
            app.Slider_3.Value=h00;
            EditField_r_changeValueChanged(app, event)

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            page4
            delete(app.UIFigure)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            page_5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            page6
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '压强差(Pa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 0.1];
            app.UIAxes.YLim = [-0.1 0.1];
            app.UIAxes.XTick = [0 0.02 0.04 0.06 0.08 0.1];
            app.UIAxes.XTickLabel = {'0'; '0.02'; '0.04'; '0.06'; '0.08'; '0.1'};
            app.UIAxes.YTick = [-0.1 -0.05 0 0.05 0.1];
            app.UIAxes.YTickLabel = {'-0.10'; '-0.05'; '0'; '0.05'; '0.10'};
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.ZTick = [];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_3.ZTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = ' 腔内某点的振动';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*a';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.MajorTicks = [0 45 90 135 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '观察点位置 r';
            app.EditField_r_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_r_change.ValueChangedFcn = createCallbackFcn(app, @EditField_r_changeValueChanged, true);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '观察点位置 θ';
            app.EditField_theta_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_theta_change.ValueChangedFcn = createCallbackFcn(app, @EditField_theta_changeValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '*L';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '观察点位置 h';
            app.EditField_h_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_h_change.ValueChangedFcn = createCallbackFcn(app, @EditField_h_changeValueChanged, true);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '方位角';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-90 90];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = '仰角';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-180 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mem_cap.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page5_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end